classdef Hopfield_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label_2                    matlab.ui.control.Label
        MetodoButtonGroup          matlab.ui.container.ButtonGroup
        PseudoInversaButton        matlab.ui.control.RadioButton
        HebbianoButton             matlab.ui.control.RadioButton
        Label                      matlab.ui.control.Label
        EntradaManualPanel         matlab.ui.container.Panel
        CheckBox64                 matlab.ui.control.CheckBox
        CheckBox63                 matlab.ui.control.CheckBox
        CheckBox62                 matlab.ui.control.CheckBox
        CheckBox61                 matlab.ui.control.CheckBox
        CheckBox60                 matlab.ui.control.CheckBox
        CheckBox59                 matlab.ui.control.CheckBox
        CheckBox58                 matlab.ui.control.CheckBox
        CheckBox57                 matlab.ui.control.CheckBox
        CheckBox56                 matlab.ui.control.CheckBox
        CheckBox55                 matlab.ui.control.CheckBox
        CheckBox54                 matlab.ui.control.CheckBox
        CheckBox53                 matlab.ui.control.CheckBox
        CheckBox52                 matlab.ui.control.CheckBox
        CheckBox51                 matlab.ui.control.CheckBox
        CheckBox50                 matlab.ui.control.CheckBox
        CheckBox49                 matlab.ui.control.CheckBox
        CheckBox48                 matlab.ui.control.CheckBox
        CheckBox47                 matlab.ui.control.CheckBox
        CheckBox46                 matlab.ui.control.CheckBox
        CheckBox45                 matlab.ui.control.CheckBox
        CheckBox44                 matlab.ui.control.CheckBox
        CheckBox43                 matlab.ui.control.CheckBox
        CheckBox42                 matlab.ui.control.CheckBox
        CheckBox41                 matlab.ui.control.CheckBox
        CheckBox40                 matlab.ui.control.CheckBox
        CheckBox39                 matlab.ui.control.CheckBox
        CheckBox38                 matlab.ui.control.CheckBox
        CheckBox37                 matlab.ui.control.CheckBox
        CheckBox36                 matlab.ui.control.CheckBox
        CheckBox35                 matlab.ui.control.CheckBox
        CheckBox34                 matlab.ui.control.CheckBox
        CheckBox33                 matlab.ui.control.CheckBox
        CheckBox32                 matlab.ui.control.CheckBox
        CheckBox31                 matlab.ui.control.CheckBox
        CheckBox30                 matlab.ui.control.CheckBox
        CheckBox29                 matlab.ui.control.CheckBox
        CheckBox28                 matlab.ui.control.CheckBox
        CheckBox27                 matlab.ui.control.CheckBox
        CheckBox26                 matlab.ui.control.CheckBox
        CheckBox25                 matlab.ui.control.CheckBox
        CheckBox24                 matlab.ui.control.CheckBox
        CheckBox23                 matlab.ui.control.CheckBox
        CheckBox22                 matlab.ui.control.CheckBox
        CheckBox21                 matlab.ui.control.CheckBox
        CheckBox20                 matlab.ui.control.CheckBox
        CheckBox19                 matlab.ui.control.CheckBox
        CheckBox18                 matlab.ui.control.CheckBox
        CheckBox17                 matlab.ui.control.CheckBox
        CheckBox16                 matlab.ui.control.CheckBox
        CheckBox15                 matlab.ui.control.CheckBox
        CheckBox14                 matlab.ui.control.CheckBox
        CheckBox13                 matlab.ui.control.CheckBox
        CheckBox12                 matlab.ui.control.CheckBox
        CheckBox11                 matlab.ui.control.CheckBox
        CheckBox10                 matlab.ui.control.CheckBox
        CheckBox9                  matlab.ui.control.CheckBox
        CheckBox8                  matlab.ui.control.CheckBox
        CheckBox7                  matlab.ui.control.CheckBox
        CheckBox6                  matlab.ui.control.CheckBox
        CheckBox5                  matlab.ui.control.CheckBox
        CheckBox4                  matlab.ui.control.CheckBox
        CheckBox3                  matlab.ui.control.CheckBox
        CheckBox2                  matlab.ui.control.CheckBox
        CheckBox                   matlab.ui.control.CheckBox
        ManualButton               matlab.ui.control.Button
        Hamming1Label              matlab.ui.control.Label
        ReconocerButton            matlab.ui.control.Button
        RedHopfieldLabel           matlab.ui.control.Label
        CargaPatronButton          matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        FuncionamientoButtonGroup  matlab.ui.container.ButtonGroup
        AsincronoButton            matlab.ui.control.RadioButton
        SincronoButton             matlab.ui.control.RadioButton
        RuidoSlider                matlab.ui.control.Slider
        RuidoSliderLabel           matlab.ui.control.Label
        EntrenarButton             matlab.ui.control.Button
    end


    properties (Access = private)
        modoFuncionamientoRed=1;
        MetodoFuncionamientoRed=1;
        imagenDistrocinada
        imagenDistrocinadaCalculos
        W
        L
        imagenManual=zeros(8)
        PatronsVector
        cantidadPatrones
    end

    methods (Access = private)
        %----------------------------------------------
        function [P,img_num]=cargarImagenesEntrenamiento(app)
            EnriqueSegoviano=uigetdir('Seleccionar Carpeta de Entrenamiento');
            EnriqueSegoviano=strcat(EnriqueSegoviano,'\');
            file_path =EnriqueSegoviano;
            img_path_list = dir(strcat(file_path,'*.png'));
            img_num = length(img_path_list);
            P=[];

            if img_num > 0
                for j = 1:img_num
                    image_name = img_path_list(j).name;
                    imagen=imread(strcat(file_path,image_name));
                    patron = double(imagen(:, :, 1)./255);
                    patron(patron == 0) = -1;
                    patron = patron(:);
                    P=[P,patron];
                end
            end
            app.PatronsVector = P;

        end
        %----------------------------------------------
        function patron = ajuste_imagen(app,imagen)
            patron = double(imagen(:, :, 1)./255);
            patron(patron == 0) = -1;
            patron(patron > 0) = 1;
            patron = patron(:);
        end
        %----------------------------------------------
        function patron = actualizar_asincronico(app, patron, iteraciones)
            app.L = length(patron);
            v = randperm(app.L); %genero indices aleatorios
            umbral = 0;
            for z =1:iteraciones
                for i=1:app.L
                    suma = app.W(v(i),:)*patron;
                    if(suma >= umbral)
                        patron(v(i)) = 1;
                    else
                        patron(v(i)) = -1;
                    end
                    areconst1 = (vec2mat(patron,sqrt(app.L)))';
                    app.Image.ImageSource =  repmat(areconst1, 1, 1, 3);
                    pause(0.001);
                end
            end
        end
        %----------------------------------------------
        function vectorCheckBoxes=crearListaCheckBox(app)
            vectorCheckBoxes=[app.CheckBox.Value,app.CheckBox2.Value,app.CheckBox3.Value,app.CheckBox4.Value,app.CheckBox5.Value,app.CheckBox6.Value,app.CheckBox7.Value,app.CheckBox8.Value;
                app.CheckBox9.Value,app.CheckBox10.Value,app.CheckBox11.Value,app.CheckBox12.Value,app.CheckBox13.Value,app.CheckBox14.Value,app.CheckBox15.Value,app.CheckBox16.Value;
                app.CheckBox17.Value,app.CheckBox18.Value,app.CheckBox19.Value,app.CheckBox20.Value,app.CheckBox21.Value,app.CheckBox22.Value,app.CheckBox23.Value,app.CheckBox24.Value;
                app.CheckBox25.Value,app.CheckBox26.Value,app.CheckBox27.Value,app.CheckBox28.Value,app.CheckBox29.Value,app.CheckBox30.Value,app.CheckBox31.Value,app.CheckBox32.Value;
                app.CheckBox33.Value,app.CheckBox34.Value,app.CheckBox35.Value,app.CheckBox36.Value,app.CheckBox37.Value,app.CheckBox38.Value,app.CheckBox39.Value,app.CheckBox40.Value;
                app.CheckBox41.Value,app.CheckBox42.Value,app.CheckBox43.Value,app.CheckBox44.Value,app.CheckBox45.Value,app.CheckBox46.Value,app.CheckBox47.Value,app.CheckBox48.Value;
                app.CheckBox49.Value,app.CheckBox50.Value,app.CheckBox51.Value,app.CheckBox52.Value,app.CheckBox53.Value,app.CheckBox54.Value,app.CheckBox55.Value,app.CheckBox56.Value;
                app.CheckBox57.Value,app.CheckBox58.Value,app.CheckBox59.Value,app.CheckBox60.Value,app.CheckBox61.Value,app.CheckBox62.Value,app.CheckBox63.Value,app.CheckBox64.Value];
        end

        function PA_R = hacerRuido(app,CantidadRuido,imagenDistrocinada)

            CantidadPosiciones=CantidadRuido*length(imagenDistrocinada);
            Posiciones=randi([1 length(imagenDistrocinada)],1,1);
            Bandera1=0;
            jj=1;

            while length(Posiciones)<CantidadPosiciones
                rng('shuffle')
                Vrandom=randi([1,length(imagenDistrocinada)]);
                Contador=0;
                for jj=1:length(Posiciones)
                    if Posiciones(jj)~=Vrandom
                        Contador=Contador+1;
                    else
                        Bandera1=1;
                        break;
                    end
                end

                if Contador == length(Posiciones)
                    Posiciones=[Posiciones Vrandom];
                end
            end

            Posiciones=sort(Posiciones);
            PA_R=imagenDistrocinada;

            for i=1:length(Posiciones)
                if imagenDistrocinada(Posiciones(i))==-1
                    PA_R(Posiciones(i))=1;
                else
                    PA_R(Posiciones(i))=-1;
                end
            end
        end

        function [Hamming_de_Patrones,Hamming_de_Patrones_inversos]=reconocimientoPatrones(app,r)

                Hamming_de_Patrones=zeros(1,app.cantidadPatrones);
                Hamming_de_Patrones_inversos=zeros(1,app.cantidadPatrones);

                InversaPatrones=app.PatronsVector;
                InversaPatrones(InversaPatrones == 1) = 0;
                InversaPatrones(InversaPatrones == -1) = 1;
                InversaPatrones(InversaPatrones == 0) = -1;

                for i=1:app.cantidadPatrones

                    b =sum(r~=app.PatronsVector(:,i));
                    Hamming_de_Patrones(1,i)=b;

                    b_i =sum(r~=InversaPatrones(:,i));
                    Hamming_de_Patrones_inversos(1,i)=b_i;
                end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            [P,img_num]=cargarImagenesEntrenamiento(app);
            %P = [i1, i2, i3]; %Matriz de patrones
            N = img_num; %Numero de patrones
            app.cantidadPatrones=N;
            app.L = length(P); %Numero de neuronas
            %             app.W = (P * P') - N * eye(app.L); %Matris de pesos sincrónica
            app.W = (P*pinv(P)); %Matris de pesos sincrónica
            if app.MetodoFuncionamientoRed ==1
                app.W = (P * P') - N * eye(app.L); %Matris de pesos sincrónica
                %disp("Funcionamiento Hebbiano")
                
            elseif app.MetodoFuncionamientoRed ==0
                app.W = (P*pinv(P));
                %disp("Funcionamiento Pseudoinversa")
            end

        end

        % Button pushed function: CargaPatronButton
        function CargaPatronButtonPushed(app, event)
            imagen=imread(uigetfile({'*.*';'*.png';"*.jpg"},'Select a Noisy Image for the Hopfield neural Network') );%
            app.imagenDistrocinada= ajuste_imagen(app,imagen) 

            %pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Image.ImageSource = repmat(imresize(vec2mat(app.imagenDistrocinada,sqrt(app.L))',10), 1, 1, 3);
            app.imagenDistrocinadaCalculos=app.imagenDistrocinada
        end

        % Selection changed function: FuncionamientoButtonGroup
        function FuncionamientoButtonGroupSelectionChanged(app, event)
            selectedButton = app.FuncionamientoButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text, 'Asincrono')
                app.modoFuncionamientoRed = 1;

            elseif strcmp(selectedButton.Text,'Sincrono')
                app.modoFuncionamientoRed = 0;
            end

            disp(app.modoFuncionamientoRed)
        end

        % Button pushed function: ReconocerButton
        function ReconocerButtonPushed(app, event)
           app.Label_2.Text = " ";
            if app.modoFuncionamientoRed ==0
                % Aplico algoritmo de Hopfield Sincrono
                d1=1000000;
                numero_iteraciones_while=0;
                hebiano_sincrono=true;

                while hebiano_sincrono
                    numero_iteraciones_while=numero_iteraciones_while+1;
                    %disp("El tamaño de app.imagenDistrocinadaCalculos")
                    %disp(app.imagenDistrocinadaCalculos )
                    r = sign(app.W * app.imagenDistrocinadaCalculos);
                    d1 = sum(r~=app.imagenDistrocinadaCalculos);
                    reconst1 = (vec2mat(r,sqrt(app.L)))';
                    
                    app.Image.ImageSource =  repmat(reconst1, 1, 1, 3);
                    app.Hamming1Label.Text = strcat('Hamming 1: ',num2str(d1));

                    if app.imagenDistrocinadaCalculos==r
                        hebiano_sincrono=false;
                       % disp("Imagenes Iguales")
                    end
                    if numero_iteraciones_while >=50
                        hebiano_sincrono=false;
                    end
                    app.imagenDistrocinadaCalculos=r;
                    
                    
                end
                app.Label_2.Text = "Ok";

                [Hamming_de_Patrones,Hamming_de_Patrones_inversos]=reconocimientoPatrones(app,r);
                
                Hamming_de_Patrones
                Hamming_de_Patrones_inversos


            end
            
            if app.modoFuncionamientoRed ==1

                ad1=1000000;
                numero_iteraciones_while=0;
                hebiano_sincrono=true;

                while hebiano_sincrono
                    numero_iteraciones_while=numero_iteraciones_while+1;
                    ar = actualizar_asincronico(app, app.imagenDistrocinadaCalculos, 1);
                    ad1 = sum(ar~=app.imagenDistrocinadaCalculos);
                    %disp("El tamaño de L")
                    disp(app.L)
                    areconst1 = (vec2mat(ar,sqrt(app.L)))';

                    
                    app.Image.ImageSource =  repmat(areconst1, 1, 1, 3);
                    app.Hamming1Label.Text = strcat('Hamming 1: ',num2str(ad1));

                    if app.imagenDistrocinadaCalculos==ar
                        hebiano_sincrono=false;
                        %disp("Imagenes Iguales")
                    end
                    if numero_iteraciones_while >=50
                        hebiano_sincrono=false;
                    end
                    app.imagenDistrocinadaCalculos=ar;
                end
                
                app.Label_2.Text = "Ok"; 
                [Hamming_de_Patrones,Hamming_de_Patrones_inversos]=reconocimientoPatrones(app,ar);
                
                Hamming_de_Patrones
                Hamming_de_Patrones_inversos
                
            end


        end

        % Value changed function: RuidoSlider
        function RuidoSliderValueChanged(app, event)
            value = app.RuidoSlider.Value;
            disp(value/100)
            %se genera un Update de con ruido cada que cambia el valor del
            %slider
            app.Label.Text= strcat(num2str(floor(value)),' %');
            app.imagenDistrocinadaCalculos= hacerRuido(app,value/100,app.imagenDistrocinada);
            app.Image.ImageSource =  repmat(imresize(vec2mat(app.imagenDistrocinadaCalculos,sqrt(app.L))',10), 1, 1, 3);
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            vectorCheckBoxes=crearListaCheckBox(app);
            app.imagenManual=-vectorCheckBoxes;
            app.imagenManual(app.imagenManual == 0)=1;
            app.imagenManual(app.imagenManual == -1)=0;

            app.imagenDistrocinada=ajuste_imagen(app,app.imagenManual);
            app.imagenDistrocinadaCalculos=app.imagenDistrocinada;

           length(app.imagenManual)   
           app.L


            app.Image.ImageSource = repmat(imresize(vec2mat(app.imagenDistrocinada,sqrt(app.L))',10), 1, 1, 3);
        end

        % Selection changed function: MetodoButtonGroup
        function MetodoButtonGroupSelectionChanged(app, event)
            selectedButton = app.MetodoButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text, 'Hebbiano')
                app.MetodoFuncionamientoRed = 1;

            elseif strcmp(selectedButton.Text,'PseudoInversa')
                app.MetodoFuncionamientoRed = 0;
            end
            disp(app.MetodoFuncionamientoRed)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EntrenarButton = uibutton(app.UIFigure, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.RuidoSliderLabel = uilabel(app.UIFigure);
            app.RuidoSliderLabel.Text = 'Ruido';
            app.RuidoSlider = uislider(app.UIFigure);
            app.RuidoSlider.ValueChangedFcn = createCallbackFcn(app, @RuidoSliderValueChanged, true);
            app.FuncionamientoButtonGroup = uibuttongroup(app.UIFigure);
            app.FuncionamientoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FuncionamientoButtonGroupSelectionChanged, true);
            app.FuncionamientoButtonGroup.Title = 'Funcionamiento';
            app.SincronoButton = uiradiobutton(app.FuncionamientoButtonGroup);
            app.SincronoButton.Text = 'Sincrono';
            app.AsincronoButton = uiradiobutton(app.FuncionamientoButtonGroup);
            app.AsincronoButton.Text = 'Asincrono';
            app.AsincronoButton.Value = true;
            app.Image = uiimage(app.UIFigure);
            app.CargaPatronButton = uibutton(app.UIFigure, 'push');
            app.CargaPatronButton.ButtonPushedFcn = createCallbackFcn(app, @CargaPatronButtonPushed, true);
            app.CargaPatronButton.Text = 'Carga Patron';
            app.RedHopfieldLabel = uilabel(app.UIFigure);
            app.RedHopfieldLabel.Text = 'Red Hopfield';
            app.ReconocerButton = uibutton(app.UIFigure, 'push');
            app.ReconocerButton.ButtonPushedFcn = createCallbackFcn(app, @ReconocerButtonPushed, true);
            app.ReconocerButton.Text = 'Reconocer';
            app.Hamming1Label = uilabel(app.UIFigure);
            app.Hamming1Label.Text = 'Hamming 1:';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.EntradaManualPanel = uipanel(app.UIFigure);
            app.EntradaManualPanel.Title = 'Entrada Manual';
            app.CheckBox = uicheckbox(app.EntradaManualPanel);
            app.CheckBox2 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox2.Text = 'Check Box2';
            app.CheckBox3 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox3.Text = 'Check Box3';
            app.CheckBox4 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox4.Text = 'Check Box4';
            app.CheckBox5 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox5.Text = 'Check Box5';
            app.CheckBox6 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox6.Text = 'Check Box6';
            app.CheckBox7 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox7.Text = 'Check Box7';
            app.CheckBox8 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox8.Text = 'Check Box8';
            app.CheckBox9 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox9.Text = 'Check Box9';
            app.CheckBox10 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox10.Text = 'Check Box10';
            app.CheckBox11 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox11.Text = 'Check Box11';
            app.CheckBox12 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox12.Text = 'Check Box12';
            app.CheckBox13 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox13.Text = 'Check Box13';
            app.CheckBox14 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox14.Text = 'Check Box14';
            app.CheckBox15 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox15.Text = 'Check Box15';
            app.CheckBox16 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox16.Text = 'Check Box16';
            app.CheckBox17 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox17.Text = 'Check Box17';
            app.CheckBox18 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox18.Text = 'Check Box18';
            app.CheckBox19 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox19.Text = 'Check Box19';
            app.CheckBox20 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox20.Text = 'Check Box20';
            app.CheckBox21 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox21.Text = 'Check Box21';
            app.CheckBox22 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox22.Text = 'Check Box22';
            app.CheckBox23 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox23.Text = 'Check Box23';
            app.CheckBox24 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox24.Text = 'Check Box24';
            app.CheckBox25 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox25.Text = 'Check Box25';
            app.CheckBox26 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox26.Text = 'Check Box26';
            app.CheckBox27 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox27.Text = 'Check Box27';
            app.CheckBox28 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox28.Text = 'Check Box28';
            app.CheckBox29 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox29.Text = 'Check Box29';
            app.CheckBox30 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox30.Text = 'Check Box30';
            app.CheckBox31 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox31.Text = 'Check Box31';
            app.CheckBox32 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox32.Text = 'Check Box32';
            app.CheckBox33 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox33.Text = 'Check Box33';
            app.CheckBox34 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox34.Text = 'Check Box34';
            app.CheckBox35 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox35.Text = 'Check Box35';
            app.CheckBox36 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox36.Text = 'Check Box36';
            app.CheckBox37 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox37.Text = 'Check Box37';
            app.CheckBox38 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox38.Text = 'Check Box38';
            app.CheckBox39 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox39.Text = 'Check Box39';
            app.CheckBox40 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox40.Text = 'Check Box40';
            app.CheckBox41 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox41.Text = 'Check Box41';
            app.CheckBox42 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox42.Text = 'Check Box42';
            app.CheckBox43 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox43.Text = 'Check Box43';
            app.CheckBox44 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox44.Text = 'Check Box44';
            app.CheckBox45 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox45.Text = 'Check Box45';
            app.CheckBox46 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox46.Text = 'Check Box46';
            app.CheckBox47 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox47.Text = 'Check Box47';
            app.CheckBox48 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox48.Text = 'Check Box48';
            app.CheckBox49 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox49.Text = 'Check Box49';
            app.CheckBox50 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox50.Text = 'Check Box50';
            app.CheckBox51 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox51.Text = 'Check Box51';
            app.CheckBox52 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox52.Text = 'Check Box52';
            app.CheckBox53 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox53.Text = 'Check Box53';
            app.CheckBox54 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox54.Text = 'Check Box54';
            app.CheckBox55 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox55.Text = 'Check Box55';
            app.CheckBox56 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox56.Text = 'Check Box56';
            app.CheckBox57 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox57.Text = 'Check Box57';
            app.CheckBox58 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox58.Text = 'Check Box58';
            app.CheckBox59 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox59.Text = 'Check Box59';
            app.CheckBox60 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox60.Text = 'Check Box60';
            app.CheckBox61 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox61.Text = 'Check Box61';
            app.CheckBox62 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox62.Text = 'Check Box62';
            app.CheckBox63 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox63.Text = 'Check Box63';
            app.CheckBox64 = uicheckbox(app.EntradaManualPanel);
            app.CheckBox64.Text = 'Check Box64';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.MetodoButtonGroup = uibuttongroup(app.UIFigure);
            app.MetodoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MetodoButtonGroupSelectionChanged, true);
            app.MetodoButtonGroup.Title = 'Metodo';
            app.HebbianoButton = uiradiobutton(app.MetodoButtonGroup);
            app.HebbianoButton.Text = 'Hebbiano';
            app.HebbianoButton.Value = true;
            app.PseudoInversaButton = uiradiobutton(app.MetodoButtonGroup);
            app.PseudoInversaButton.Text = 'PseudoInversa';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hopfield_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end